classdef ipcalc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        addrFormatSignal      matlab.ui.control.Lamp
        maskFormatSignal      matlab.ui.control.Lamp
        UITable               matlab.ui.control.Table
        AnalyzovatButton      matlab.ui.control.Button
        MaskaEditField        matlab.ui.control.EditField
        MaskaEditFieldLabel   matlab.ui.control.Label
        AdresaEditField       matlab.ui.control.EditField
        AdresaEditFieldLabel  matlab.ui.control.Label
        PrefixEditField       matlab.ui.control.NumericEditField
        IPv4KalkulakaLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        isAddressFormatOk % Description
        isMaskFormatOk % Description
        ipAdresa % Description
        ipMaska % Description
        pocetJednicek % Description
    end
    
    methods (Access = private)
        
        function results = setAddrFormatStatus(app, isOK)
            if (isOK)
                app.isAddressFormatOk = true;
                app.addrFormatSignal.Color = [0.00,1.00,0.00];
            else
                app.isAddressFormatOk = false;
                app.addrFormatSignal.Color = [1.00,0.00,0.00];
            end
        end
        
        function results = setMaskFormatStatus(app, isOK)
            if (isOK)
                app.isMaskFormatOk = true;
                app.maskFormatSignal.Color = [0.00,1.00,0.00];
            else
                app.isMaskFormatOk = false;
                app.maskFormatSignal.Color = [1.00,0.00,0.00];
            end
        end
        
        function results = addTableRow(app, key, value)
            tempt = table(key, value, 'VariableNames',{'Vlastnost','Hodnota'});
            app.UITable.Data = [app.UITable.Data; tempt];
        end
        
        function results = clearTable(app)
            app.UITable.Data = [];
        end
        
        function trida = tridaAdresy(app, adresa)
            oktety = str2double(split(adresa, "."));

            switch oktety(1)
                case num2cell(0:127)
                    trida = "A";
                case num2cell(128:191)
                    trida = "B";
                case num2cell(192:223)
                    trida = "C";
                case num2cell(224:239)
                    trida = "D";
                otherwise
                    trida = "E";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AdresaEditField
        function AdresaEditFieldValueChanged(app, event)
            value = app.AdresaEditField.Value;
            oktety = split(value, ".");
            
            app.ipAdresa = "";
            app.setAddrFormatStatus(false);

            if (numel(oktety) ~= 4)
                msgbox('IP adresa se musí skládat ze 4 oktetů oddělených tečkami.', 'Špatný formát','error');
                return;
            end
            
            oktety = str2double(oktety)

            if (any(isnan(oktety)))
                msgbox('IP adresa se musí skládat ze 4 celočíselných oktetů', 'Špatný formát','error');
                return;
            end

            if (any(oktety < 0) || any(oktety > 255))
                msgbox('Oktety musí být v rozsahu od 0 do 255', 'Špatný formát','error');
                return;
            end
            
            app.ipAdresa = value;
            app.setAddrFormatStatus(true);
        end

        % Value changed function: MaskaEditField
        function MaskaEditFieldValueChanged(app, event)
            value = app.MaskaEditField.Value;
            oktety = split(value, ".");

            app.ipMaska = "";
            app.setMaskFormatStatus(false);
            
            %Kontrola, jestli mám 4 oktety
            if (numel(oktety) ~= 4)
                msgbox('Maska se musí skládat ze 4 oktetů oddělených tečkami.', 'Špatný formát','error');
                return;
            end
            
            oktety = str2double(oktety)
            
            %Kontrola, jestli jsou oktety vůbec čísla
            if (any(isnan(oktety)))
                msgbox('Maska se musí skládat ze 4 celočíselných oktetů', 'Špatný formát','error');
                return;
            end
            
            %Kontrola, jestli jsou oktety v mezi jednoho bajtu
            if (any(oktety < 0) || any(oktety > 255))
                msgbox('Oktety musí být v rozsahu od 0 do 255', 'Špatný formát','error');
                return;
            end
            
            validniOktety = zeros(1, 8);

            %Vytvoření validních oktetů
            for i=1:8
                test = [0 0 0 0 0 0 0 0];
                test(1:i) = 1;
                validniOktety(i) = bin2dec(join(string(test), ""));
            end
           
            validniOktety(end + 1) = 0

            %Kontrola, jestli má maska pouze validní oktety
            if (any(ismember(oktety, validniOktety) == 0))
                msgbox('Maska není platná.', 'Špatný formát','error');
                return;
            end

            prvniNulovy = find(oktety ~= 255, 1, 'first')
    
            if (prvniNulovy ~= 4)
                if (any(oktety(prvniNulovy+1:end) ~= 0))
                    msgbox('Maska není platná.', 'Špatný formát','error');
                    return;
                end
            end
            
            pocetJednicek = count(join(string(dec2bin(oktety)), ""), "1");
            app.PrefixEditField.Value = pocetJednicek;
            app.pocetJednicek = pocetJednicek;
            app.ipMaska = value;
            app.setMaskFormatStatus(true);
        end

        % Value changed function: PrefixEditField
        function PrefixEditFieldValueChanged(app, event)
            value = app.PrefixEditField.Value;
            app.setMaskFormatStatus(false);

            plneOktety = fix(value/8);
            zbytek = mod(value, 8);

            maska = zeros(1, 4);
            maska(1:plneOktety) = 255;

            if (plneOktety < 4)
                maska(plneOktety + 1) = bin2dec(join([repmat("1", 1, zbytek), repmat("0", 1, 8 - zbytek)], ""));
            end
            
            app.MaskaEditField.Value = join(string(maska), ".");
            app.ipMaska = app.MaskaEditField.Value;
            app.pocetJednicek = double(value);
            app.setMaskFormatStatus(true);
        end

        % Button pushed function: AnalyzovatButton
        function AnalyzovatButtonPushed(app, event)
            app.clearTable();

            if (~app.isAddressFormatOk || ~app.isMaskFormatOk)
                msgbox('Zadané vstupní parametry nejsou platné', 'Špatný formát','error');
                return;
            end
            
            oktetyAdresy = str2double(split(app.ipAdresa, "."));
            oktetyMasky = str2double(split(app.ipMaska, "."));

            prvniNulovy = find(oktetyMasky ~= 255, 1, 'first');
            
            trida = app.tridaAdresy(app.ipAdresa);
            velikostBloku = 256 - oktetyMasky(prvniNulovy);
            cisloPodsite = fix(oktetyAdresy(prvniNulovy) / velikostBloku);
            
            baze = oktetyAdresy;
            baze(prvniNulovy) = velikostBloku * cisloPodsite;
            baze(prvniNulovy + 1:end) = 0;
            baze = join(string(baze), ".");
            
            broadcast = oktetyAdresy;
            broadcast(prvniNulovy) = (velikostBloku * cisloPodsite) + velikostBloku - 1;
            broadcast(prvniNulovy + 1:end) = 255;
            broadcast = join(string(broadcast), ".");
            
            pocetHostu = 2^(32 - app.pocetJednicek) - 2;
            
            prvniPouzitelna = double(split(baze, "."));
            prvniPouzitelna(end) = prvniPouzitelna(end) + 1;
            prvniPouzitelna = join(string(prvniPouzitelna), ".");

            posledniPouzitelna = double(split(broadcast, "."));
            posledniPouzitelna(end) = posledniPouzitelna(end) - 1;
            posledniPouzitelna = join(string(posledniPouzitelna), ".");
            
            app.addTableRow("Třída", trida);
            app.addTableRow("Velikost bloku", velikostBloku);
            app.addTableRow("Číslo podsítě", cisloPodsite);
            app.addTableRow("Báze (pod)sítě", baze);
            app.addTableRow("Broadcast (pod)sítě", broadcast);
            app.addTableRow("Počet hostů v (pod)síti", pocetHostu);
            app.addTableRow("První použitelná adresa", prvniPouzitelna);
            app.addTableRow("Poslední použitelná adresa", posledniPouzitelna);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IPv4KalkulakaLabel = uilabel(app.UIFigure);
            app.IPv4KalkulakaLabel.Text = 'IPv4 Kalkulačka';
            app.PrefixEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrefixEditField.Limits = [0 32];
            app.PrefixEditField.RoundFractionalValues = 'on';
            app.PrefixEditField.ValueChangedFcn = createCallbackFcn(app, @PrefixEditFieldValueChanged, true);
            app.AdresaEditFieldLabel = uilabel(app.UIFigure);
            app.AdresaEditFieldLabel.Text = 'Adresa';
            app.AdresaEditField = uieditfield(app.UIFigure, 'text');
            app.AdresaEditField.ValueChangedFcn = createCallbackFcn(app, @AdresaEditFieldValueChanged, true);
            app.MaskaEditFieldLabel = uilabel(app.UIFigure);
            app.MaskaEditFieldLabel.Text = 'Maska';
            app.MaskaEditField = uieditfield(app.UIFigure, 'text');
            app.MaskaEditField.ValueChangedFcn = createCallbackFcn(app, @MaskaEditFieldValueChanged, true);
            app.AnalyzovatButton = uibutton(app.UIFigure, 'push');
            app.AnalyzovatButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzovatButtonPushed, true);
            app.AnalyzovatButton.Text = 'Analyzovat';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Vlastnost'; 'Hodnota'};
            app.UITable.RowName = {};
            app.maskFormatSignal = uilamp(app.UIFigure);
            app.addrFormatSignal = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ipcalc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end